classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Tree      matlab.ui.container.Tree
        Node      matlab.ui.container.TreeNode
        Node2     matlab.ui.container.TreeNode
        Node5     matlab.ui.container.TreeNode
        Node3     matlab.ui.container.TreeNode
        Node_2    matlab.ui.container.TreeNode
        Node4     matlab.ui.container.TreeNode
        Node2_2   matlab.ui.container.TreeNode
        Node3_2   matlab.ui.container.TreeNode
        UITable   matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Tree.Multiselect = 'on';
            pic = fullfile(matlabroot, 'toolbox', 'image', 'icon', 'icon_info.png');
            t0 = logical([1 1 0])';
            t1 = {'gg', 'eqw', 'grw'}';
            t2 = [52; 62; 12];
            t3 = {'<html><img height="16" width="16" src="file:/C:/Users/Dmitri/AppData/Roaming/MathWorks/MATLAB Add-Ons/Toolboxes/wit_io/icons/icons (16x16)/Default.png"/>&nbsp;Global (Calibration Information)</html>','foo','bar'}';
            
            app.UITable.Data = table(t0, t1,t2,t3);
            app.UITable.BackgroundColor = [0 0 0];
            
            app.Tree.BackgroundColor = [0 0 0];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Tree = uitree(app.UIFigure);
            app.Tree.Multiselect = 'on';
            app.Tree.Editable = 'on';
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Icon = 'TDLUTTransformation.png';
            app.Node2.Text = 'Node2';
            app.Node5 = uitreenode(app.Node);
            app.Node5.Icon = 'TDCursor.png';
            app.Node5.Text = 'Node5';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Icon = 'Default.png';
            app.Node3.Text = 'Node3';
            app.Node_2 = uitreenode(app.Node3);
            app.Node_2.Icon = 'TDGraph_0.png';
            app.Node_2.Text = 'Node';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Icon = 'TDGraph_0.png';
            app.Node4.Text = 'Node4';
            app.Node2_2 = uitreenode(app.Tree);
            app.Node2_2.Icon = 'TDGraph_1.png';
            app.Node2_2.Text = 'Node2';
            app.Node3_2 = uitreenode(app.Tree);
            app.Node3_2.Text = 'Node3';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Sel'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {''};
            app.UITable.ColumnEditable = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end