classdef ramatgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DVRamanToolUIFigure             matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        OpenRecentMenu                  matlab.ui.container.Menu
        emptyMenu                       matlab.ui.container.Menu
        ImportDataMenu                  matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SaveAsMenu                      matlab.ui.container.Menu
        ExportDataMenu                  matlab.ui.container.Menu
        CloseMenu                       matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        SelectAllMenu                   matlab.ui.container.Menu
        DuplicateMenu                   matlab.ui.container.Menu
        RemoveMenu                      matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        HelpMenu_2                      matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        WorkspacePanel                  matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        OpenButton                      matlab.ui.control.Button
        NewButton                       matlab.ui.control.Button
        SpectralCorrectionPanel         matlab.ui.container.Panel
        OverwriteCheckBox               matlab.ui.control.CheckBox
        TrimButton                      matlab.ui.control.Button
        CosmicRayRemovalButton          matlab.ui.control.Button
        RemoveBaselineButton            matlab.ui.control.Button
        NormalizeButton                 matlab.ui.control.Button
        PeakAnalysisPanel               matlab.ui.container.Panel
        MinimumDistanceEditField        matlab.ui.control.NumericEditField
        MinimumDistanceEditFieldLabel   matlab.ui.control.Label
        AutoCheckBox_2                  matlab.ui.control.CheckBox
        AutoCheckBox                    matlab.ui.control.CheckBox
        MinimumHeightEditField          matlab.ui.control.NumericEditField
        MinimumHeightEditFieldLabel     matlab.ui.control.Label
        PlotPeaksButton                 matlab.ui.control.Button
        ExtractPeakTableButton          matlab.ui.control.Button
        PlottingPanel                   matlab.ui.container.Panel
        SpectralPlotButton              matlab.ui.control.Button
        AreaScansTab                    matlab.ui.container.Tab
        PCATab                          matlab.ui.container.Tab
        OverviewPanel                   matlab.ui.container.Panel
        pcaResSummaryTable              matlab.ui.control.Table
        ListBox                         matlab.ui.control.ListBox
        DAnalysisPanel_2                matlab.ui.container.Panel
        ShowConfidenceEllipsesCheckBox  matlab.ui.control.CheckBox
        OptionsButton                   matlab.ui.control.Button
        ConfidenceIntervalButtonGroup   matlab.ui.container.ButtonGroup
        RadioConfInt999                 matlab.ui.control.RadioButton
        RadioConfInt990                 matlab.ui.control.RadioButton
        RadioConfInt950                 matlab.ui.control.RadioButton
        Scores2DScatterPlotButton       matlab.ui.control.Button
        DAnalysisPanel                  matlab.ui.container.Panel
        PCYSpinner                      matlab.ui.control.Spinner
        PCYSpinnerLabel                 matlab.ui.control.Label
        PCXSpinner                      matlab.ui.control.Spinner
        PCXSpinnerLabel                 matlab.ui.control.Label
        ScoresButtonY                   matlab.ui.control.Button
        ScoresButtonX                   matlab.ui.control.Button
        LoadingsButtonX                 matlab.ui.control.Button
        LoadingsButtonY                 matlab.ui.control.Button
        CreatePCAPanel                  matlab.ui.container.Panel
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        PCAStartRangeField              matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        PCAEndRangeField                matlab.ui.control.NumericEditField
        cm1EditFieldLabel               matlab.ui.control.Label
        RangeButton                     matlab.ui.control.RadioButton
        EntireSpectrumButton            matlab.ui.control.RadioButton
        AnalyzeButton                   matlab.ui.control.Button
        GroupsPanel                     matlab.ui.container.Panel
        AssignButton                    matlab.ui.control.Button
        VButton                         matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        ButtonGroupDelete               matlab.ui.control.Button
        ButtonGroupAdd                  matlab.ui.control.Button
        UIGroupsTable                   matlab.ui.control.Table
        PreviewPanel                    matlab.ui.container.Panel
        ExportButton_2                  matlab.ui.control.Button
        SetDefaultButton                matlab.ui.control.Button
        CreateInstanceButton            matlab.ui.control.Button
        RemoveButton_2                  matlab.ui.control.Button
        DataItemsTable                  matlab.ui.control.Table
        PreviewRangeField               matlab.ui.control.EditField
        RangeEditFieldLabel             matlab.ui.control.Label
        PreviewSumField                 matlab.ui.control.EditField
        SumEditFieldLabel               matlab.ui.control.Label
        UIPreviewAxes                   matlab.ui.control.UIAxes
        MeasurementsPanel               matlab.ui.container.Panel
        ListBox_2                       matlab.ui.control.ListBox
        ListBox_2Label                  matlab.ui.control.Label
        DataTree                        matlab.ui.container.CheckBoxTree
        ExportSelectedButton            matlab.ui.control.Button
        DuplicateButton                 matlab.ui.control.Button
        SelectAllNoneButton             matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        UISamplesTable                  matlab.ui.control.Table
    end

    
    properties (Access = private)
        DataSet
        tblGroups
        SampleSelectionLogic
        HighlightedSampleIndices
        ImportDialog
        SpectralPlotDialog
        PCAResult = struct;     % Struct Containing PCA Results
        UIHighlighted = struct;
        OpenedDialogs = struct;
        rootdir % Root directory of executable
    end
    
    properties (Access = public)
        prj % The handle to the Project() Object
    end
    
    methods (Access = private)
        
        function updatePreviewPlot(app,indices)
            if (size(app.UIHighlighted.samples) > 1)
                app.PreviewPanel.Title = "Multiple Samples Selected";
                app.PreviewSumField.Value = "";
                app.PreviewRangeField.Value = "";
            elseif (app.UIHighlighted.samples ~= 0)
                name = app.DataSet(indices(1)).name;
                xData = app.DataSet(indices(1)).xData;
                yData = app.DataSet(indices(1)).yData;
                
                sumSpec = sum(sum(yData));
                minShift = min(min(xData));
                maxShift = max(max(xData));
                
                app.PreviewPanel.Title = name;
                app.PreviewSumField.Value = sprintf('%.3f',sumSpec);
                app.PreviewRangeField.Value = sprintf('%.0f - %.0f cm-1',minShift, maxShift);
                plot(app.UIPreviewAxes,xData,yData);
                
                app.DataItemsTable.Data = app.DataSet(indices(1)).listDataItems;
            end

        end
        
        function loadDataSet(app)
            [file,path,indx] = uigetfile( ...
                {'*.ramat','Raman MATLAB Dataset (*.ramat)'; ...
                '*.mat','MATLAB Files (*.mat)'; ...
                '*.*',  'All Files (*.*)'}, ...
                'Select a Data Set');
            fullPath = sprintf("%s%s",path,file);
            load(fullPath);

            % Create single group in Group Table if no groups are found in
            % the dataset
            if ~exist('groups','var')
                groups = table([0],true,"Group1",'VariableNames',{'ID','Selected','GroupName'});
            end
            
                        
            app.DataSet = data;
            app.tblGroups = groups;
            app.SampleSelectionLogic = logical(zeros(length(data),1));
            
            updateTables(app);
        end
        
        function saveDataSet(app)
            data = app.DataSet;
            groups = app.tblGroups;
            uisave({'data', 'groups'},'dataset');
        end
        
        function results = getSelectedIndices(app,logicArray)
%             logicArray = app.SampleSelectionLogic;

            % Convert logic array to array of actual sample indices
            % e.g. [0 1 0 1] becomes [2 4]
            results = transpose(1:length(logicArray)) .* logicArray;
            results(results==0) = [];
        end
        
        function updateTables(app)
            % Convert Data Structure to Table and Sort Columns
            t = table(app.DataSet);
  
            g = app.tblGroups;
            t = join(t,g,'LeftKeys','group','RightKeys','ID');
            
            % Create Drop Down List Strings
            groupList = strings(height(t),1);
            groupListAll = strings(height(g),1);
            for i = 1:height(t)
                groupList(i) = sprintf("%u: %s",t.group(i),t.GroupName(i));
            end
            for i = 1:height(g)
                groupListAll(i) = sprintf("%u: %s",g.ID(i), g.GroupName(i));
            end
            t.GroupName = categorical(groupList,groupListAll);
            
            % Display Table
            t = table(t.selected, t.name, t.xUnits, t.nSpectra, t.GroupName, 'VariableNames', ...
                {'selected','name','xUnits','nSpectra','GroupName'});
            
            app.UISamplesTable.Data = t;
            app.UIGroupsTable.Data = g(:,[1 2 3]);
        end
        
        function createGroup(app)
            groups = table([1],true,"Group1",'VariableNames',{'ID','Selected','GroupName'});
            
            app.tblGroups = groups;
        end
        
        function spectralPlotStart(app)
            
            data = app.DataSet;
            
            %% HEREAFTER NON-OO CODE -----
            % Analyze Selected Groups
            % Get all sample data of selected groups
            t = table(data);
            g = app.tblGroups(app.tblGroups.Selected == true,:);
            resTable = innerjoin(t,g,'LeftKeys','group','RightKeys','ID');
            
            % Create averages if averaging of groups is selected
            % Yes this could be implemented better
            resTable = calcGroupedAverage(resTable);
            
            % Calculate number of samples per group and create a table
            % containing the name labels
            osum = @(x) sum(x);
            groupingTable = varfun(osum, resTable, 'GroupingVariables', 'group', ...
                'InputVariables','nSpectra');
            groupingTable = innerjoin(groupingTable,g,'LeftKeys','group','RightKeys','ID');
            
            app.SpectralPlotDialog = SpectralPlotDlg(app,resTable,groupingTable);
            
            
        end
        
        function removeSamples(app,indices)
            if isempty(indices)
                warning('No samples have been selected.');
                return
            else
                try
                    % Destroy objects
                    delete( app.DataSet(indices) );
                    
                    % Remove handles from object array
                    app.DataSet(indices) = [];
            
                    updateTables(app);
                catch
                    warning('Samples could not be removed.');
                end
            end
        end
        
        function loadingsPlotStart(app,pcax)
            wavenum = app.PCAResult.wavenum;
            loadings = app.PCAResult.coefs;
            plotLoadings(wavenum,loadings,pcax);
        end
        
        function assignGroupToSamples(app, group, samples)
            % Assign a group to multiple selected samples.
            
            [app.DataSet(samples).group] = deal(group);
            
            updateTables(app);
        end
        
        function newGroupID = addGroup(app)
            % Get list of current groups
            currentTable = app.tblGroups;    
            lastID = max(currentTable.ID);
            
            % Create a new group with a new ID
            newGroupID = lastID + 1;
            newName = sprintf("%s %d", "New Group", newGroupID);
            newRow = table(newGroupID, logical([1]), newName, 'VariableNames', {'ID','Selected','GroupName'});
            
            % Append to group table
            newTable = [currentTable; newRow];
            app.tblGroups = newTable;
            
            % Update GUI
            updateTables(app);
        end
        
        function status = deleteGroup(app, selectedGroupRows)
        % DELETEGROUP()
        % selectedGroupRows: states group row to be deleted, NOT ID.
            
            status = 0;
            
            if (size(selectedGroupRows) == 0)
                % Nothing has been selected, so nothing can be deleted.
                warning('No groups have been selected.');
                return
            end
            
            % Get list of current groups
            currentTable = app.tblGroups;
            selectedGroupIDs = currentTable.ID( selectedGroupRows );
            
            % Check if at least one group will remain after deletion
            nEntriesTable = height(currentTable);
            nDeletions = size(selectedGroupRows);
            
            if ((nEntriesTable - nDeletions(1)) < 1)
                warning('At least one group is needed for a dataset.');
                status = 1;
                return
            else
                
                % Check if any data is appended to the groups that are
                % about to be deleted.
                
                data = app.DataSet;
                currentGroupAssignments = vertcat( data.group );
                logicalMembers = ismember(currentGroupAssignments, selectedGroupIDs);
                                
                if (size(logicalMembers) > 0)
                    % The groups that are about to be deleted have data
                    % assigned to them
                    warning('The selected groups currently have assigned data to them. These samples have been assigned to a new group.');
                    
                    % Create a new group and assign data to it.
                    newGroupID = addGroup(app);
                    [data( logicalMembers ).group] = deal(newGroupID);
                    
                    % Update current table
                    currentTable = app.tblGroups;
                end
                
                try
                    currentTable( selectedGroupRows, : ) = [];
                    app.tblGroups = currentTable;
                catch
                    warning('Attempt to remove selected table rows failed.');
                    status = 2;
                end
                
            end
            
            % Update GUI
            updateTables(app);
        end
    end
    
    methods (Access = public)
        
        function loadImportedData(app, data)
            % Create single group
            createGroup(app);
        
            % Append Extra Data for proper functioning
            l = length(data);
            for i = 1:l
                data(i).selected = true;
                data(i).group = 1;
            end
            app.SampleSelectionLogic = transpose([data.selected]);
            
            app.DataSet = data;

            updateTables(app);
        end
        
        function trimData(app, startX, endX, trimAll, overwrite)
            
            % Create object handles
            if (trimAll == true)
                objh = app.DataSet;
            else
                objh = app.DataSet( app.HighlightedSampleIndices );
            end
            
            % Call trimData method
            objh.trimData(startX, endX, overwrite);
       
            updateTables(app);
        end
        
        function results = getSelectedMeasurements(app)
            results = app.HighlightedSampleIndices;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, initVar)
            %STARTUPFCN Initialises the GUI app
            
            % Unpack initialisation data from initVar argument
            app.prj = initVar.prj;  % Set working Project
            app.prj.Name = "New Project";
            app.rootdir = initVar.rootdir;
            
            % Set Window Title
            app.DVRamanToolUIFigure.Name = app.prj.Name;
            
            %loadDataSet(app);
            
            %Set struct
            app.UIHighlighted.samples = [0];
            app.UIHighlighted.groups = [0];
            
        end

        % Cell selection callback: UISamplesTable
        function UISamplesTableCellSelection(app, event)
            % Event indices are given in [row col]
            % Store highlighted rows, that represent the selected samples.
            indices = event.Indices;
            indices = unique(indices(:,1));
            
            app.HighlightedSampleIndices = indices;
            app.UIHighlighted.samples = indices;
            
            updatePreviewPlot(app,indices);
            
            disp(indices);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            saveDataSet(app);
        end

        % Button pushed function: Scores2DScatterPlotButton
        function Scores2DScatterPlotButtonPushed(app, event)
            % Retrieve PC Axes Spinner Values
            pcaxx = app.PCXSpinner.Value;
            pcaxy = app.PCYSpinner.Value;
            pcax = [pcaxx pcaxy];

            % Retrieve Results from PCAResult
            score = app.PCAResult.score;
            axPercentage = app.PCAResult.axPercentage;
            groupingTable = app.PCAResult.groupingTable;
            
            % Plot PCA Scores
            plotpca(score,pcax,axPercentage,groupingTable);
        end

        % Cell edit callback: UISamplesTable
        function UISamplesTableCellEdit(app, event)
            % Data in sample selection table has been changed by user
            indices = event.Indices;
            newData = event.NewData;
            
            changedRow = indices(1);
            changedCol = indices(2);
            switch changedCol
                case 1 % Selection Changed
                    app.SampleSelectionLogic(changedRow) = newData;
                    app.DataSet(changedRow).selected = newData;
                case 5 % Group Changed
                    % Get Group ID, instead of full name
                    splittedString = strsplit(string(newData), ':');
                    newGroupID = str2num(splittedString(1));
                    
                    % Set Group ID
                    app.DataSet(changedRow).group = newGroupID;
            end
            
            
            idx = getSelectedIndices(app,app.SampleSelectionLogic);
            display(idx);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            loadDataSet(app);
        end

        % Button pushed function: SelectAllNoneButton
        function SelectAllNoneButtonPushed(app, event)
            selection = app.SampleSelectionLogic;
            nSamples = length(selection);
            
            if (sum(selection) == length(selection))
                selection = zeros(nSamples,1);          % Table Set
                [app.DataSet.selected] = deal(false);   % Struct Set
            else
                selection = ones(nSamples,1);           % Table Set
                [app.DataSet.selected] = deal(true);    % Struct Set
            end
            
            app.SampleSelectionLogic = logical(selection); % Table Set
            
            updateTables(app);
        end

        % Button pushed function: ButtonGroupAdd
        function ButtonGroupAddPushed(app, event)
            addGroup(app);
        end

        % Cell edit callback: UIGroupsTable
        function UIGroupsTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            changedRow = indices(1);
            changedCol = indices(2);
            
            app.tblGroups{changedRow,changedCol} = newData;
                        
            updateTables(app);

        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            app.ImportDialog = ImportDialogApp(app);
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%%%%
            data = app.DataSet;
            
            % Analyze Selected Groups
            % Get all sample data of selected groups
            t = table(data);
            g = app.tblGroups(app.tblGroups.Selected == true,:);
            resTable = innerjoin(t,g,'LeftKeys','group','RightKeys','ID');
            
            % XUnits are expected to be the same
            % Therefore, we can take those of the first selected sample
            wavenum = resTable.xData{1};
            
            % Prepare Sample Data for PCA
            inputData = transpose([resTable.yData{:}]);
            
            % Calculate number of samples per group and create a table
            % containing the name labels
            osum = @(x) sum(x);
            groupingTable = varfun(osum, resTable, 'GroupingVariables', 'group', ...
                'InputVariables','nSpectra');
            groupingTable = innerjoin(groupingTable,g,'LeftKeys','group','RightKeys','ID');

            % Calculate only specific Data Range
            startWavenum = app.PCAStartRangeField.Value;
            endWavenum = app.PCAEndRangeField.Value;
            startIdx = find(wavenum > startWavenum, 1, 'first');
            endIdx = find(wavenum < endWavenum, 1, 'last');

            inputData = inputData(:,startIdx:endIdx);
            wavenum = wavenum(startIdx:endIdx);
            
            [coefs,score,~,~,axPercentage] = pca(inputData);
            
            % Store Information in PCAResult
            app.PCAResult.coefs = coefs;
            app.PCAResult.score = score;
            app.PCAResult.axPercentage = axPercentage;
            app.PCAResult.inputData = inputData;
            app.PCAResult.groupingTable = groupingTable;
            app.PCAResult.wavenum = wavenum;
            
            % Update Summary Table
            resTableSummary = table(groupingTable.GroupName, groupingTable.Fun_nSpectra);
            app.pcaResSummaryTable.Data = resTableSummary;
            
        end

        % Button pushed function: SpectralPlotButton
        function SpectralPlotButtonPushed(app, event)
            spectralPlotStart(app);
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            indices = app.UIHighlighted.samples;    
            removeSamples(app,indices);
        end

        % Button pushed function: LoadingsButtonX
        function LoadingsButtonXPushed(app, event)
            pcax = app.PCXSpinner.Value;
            loadingsPlotStart(app,pcax);
        end

        % Button pushed function: AssignButton
        function AssignButtonPushed(app, event)
            if (app.UIHighlighted.groups ~= 0 && min(app.UIHighlighted.samples) ~= 0)
                if size(app.UIHighlighted.groups) > 1
                    warning('You can only assign one group to the samples.');
                else
                    
                    group = app.UIHighlighted.groups;
                    samples = app.UIHighlighted.samples;
                    
                    try
                        assignGroupToSamples(app,group,samples)
                    catch ME
                        warning('Could not assign group to samples.');
                    end
                end
            end
        end

        % Cell selection callback: UIGroupsTable
        function UIGroupsTableCellSelection(app, event)
            % Event indices are given in [row col]
            % Store highlighted rows, that represent the selected samples.
            indices = event.Indices;
            indices = unique(indices(:,1));
            
            app.UIHighlighted.groups = indices;
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            app.OpenedDialogs.AboutDlg = AboutDlg(app);
        end

        % Button pushed function: TrimButton
        function TrimButtonPushed(app, event)
            app.OpenedDialogs = TrimToDlg(app);
        end

        % Button pushed function: NormalizeButton
        function NormalizeButtonPushed(app, event)
            overwriteBool = app.OverwriteCheckBox.Value;
            
            % Get object handles
            objh = app.DataSet( app.HighlightedSampleIndices );
            
            % Normalize Data
            objh.normalizeData( overwriteBool );
        end

        % Button pushed function: LoadingsButtonY
        function LoadingsButtonYPushed(app, event)
            pcay = app.PCYSpinner.Value;
            loadingsPlotStart(app,pcay);
        end

        % Value changed function: ShowConfidenceEllipsesCheckBox
        function ShowConfidenceEllipsesCheckBoxValueChanged(app, event)
            value = app.ShowConfidenceEllipsesCheckBox.Value;
            
            if (value == true)
                app.RadioConfInt950.Enable = true;
                app.RadioConfInt990.Enable = true;
                app.RadioConfInt999.Enable = true;
            else
                app.RadioConfInt950.Enable = false;
                app.RadioConfInt990.Enable = false;
                app.RadioConfInt999.Enable = false;
            end
        end

        % Button pushed function: ButtonGroupDelete
        function ButtonGroupDeletePushed(app, event)
            % Retrieve selected groups
            groupID = app.UIHighlighted.groups;
            
            % Delete groups
            try
                deleteGroup(app, groupID);
            catch
                warning('An error has occurred: Could not properly delete selected groups.');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DVRamanToolUIFigure = uifigure('Visible', 'off');
            app.DVRamanToolUIFigure.Name = 'DV Raman Tool';
            app.FileMenu = uimenu(app.DVRamanToolUIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Separator = 'on';
            app.OpenMenu.Text = 'Open...';
            app.OpenRecentMenu = uimenu(app.FileMenu);
            app.OpenRecentMenu.Text = 'Open Recent';
            app.emptyMenu = uimenu(app.OpenRecentMenu);
            app.emptyMenu.Text = '*empty*';
            app.ImportDataMenu = uimenu(app.FileMenu);
            app.ImportDataMenu.Text = 'Import Data...';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Separator = 'on';
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.Text = 'Save As...';
            app.ExportDataMenu = uimenu(app.FileMenu);
            app.ExportDataMenu.Text = 'Export Data...';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.Separator = 'on';
            app.CloseMenu.Text = 'Close';
            app.EditMenu = uimenu(app.DVRamanToolUIFigure);
            app.EditMenu.Text = 'Edit';
            app.SelectAllMenu = uimenu(app.EditMenu);
            app.SelectAllMenu.Text = 'Select All';
            app.DuplicateMenu = uimenu(app.EditMenu);
            app.DuplicateMenu.Text = 'Duplicate';
            app.RemoveMenu = uimenu(app.EditMenu);
            app.RemoveMenu.Text = 'Remove';
            app.HelpMenu = uimenu(app.DVRamanToolUIFigure);
            app.HelpMenu.Text = 'Help';
            app.HelpMenu_2 = uimenu(app.HelpMenu);
            app.HelpMenu_2.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.MeasurementsPanel = uipanel(app.DVRamanToolUIFigure);
            app.MeasurementsPanel.Title = 'Measurements';
            app.UISamplesTable = uitable(app.MeasurementsPanel);
            app.UISamplesTable.ColumnName = {'Name'; 'Units'; 'Spectra'; 'Group'};
            app.UISamplesTable.RowName = {};
            app.UISamplesTable.ColumnEditable = [true false false true];
            app.UISamplesTable.CellEditCallback = createCallbackFcn(app, @UISamplesTableCellEdit, true);
            app.UISamplesTable.CellSelectionCallback = createCallbackFcn(app, @UISamplesTableCellSelection, true);
            app.ImportButton = uibutton(app.MeasurementsPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import...';
            app.RemoveButton = uibutton(app.MeasurementsPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.SelectAllNoneButton = uibutton(app.MeasurementsPanel, 'push');
            app.SelectAllNoneButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllNoneButtonPushed, true);
            app.SelectAllNoneButton.Text = 'Select All/None';
            app.DuplicateButton = uibutton(app.MeasurementsPanel, 'push');
            app.DuplicateButton.Text = 'Duplicate';
            app.ExportSelectedButton = uibutton(app.MeasurementsPanel, 'push');
            app.ExportSelectedButton.Text = 'Export Selected...';
            app.DataTree = uitree(app.MeasurementsPanel, 'checkbox');
            app.ListBox_2Label = uilabel(app.MeasurementsPanel);
            app.ListBox_2Label.Text = 'List Box';
            app.ListBox_2 = uilistbox(app.MeasurementsPanel);
            app.ListBox_2.Multiselect = 'on';
            app.ListBox_2.Value = {'Item 1'};
            app.PreviewPanel = uipanel(app.DVRamanToolUIFigure);
            app.PreviewPanel.Title = 'Preview';
            app.UIPreviewAxes = uiaxes(app.PreviewPanel);
            app.SumEditFieldLabel = uilabel(app.PreviewPanel);
            app.SumEditFieldLabel.Text = 'Sum';
            app.PreviewSumField = uieditfield(app.PreviewPanel, 'text');
            app.PreviewSumField.Editable = 'off';
            app.RangeEditFieldLabel = uilabel(app.PreviewPanel);
            app.RangeEditFieldLabel.Text = 'Range';
            app.PreviewRangeField = uieditfield(app.PreviewPanel, 'text');
            app.PreviewRangeField.Editable = 'off';
            app.DataItemsTable = uitable(app.PreviewPanel);
            app.DataItemsTable.ColumnName = {''; 'Type'; 'Description'};
            app.DataItemsTable.RowName = {};
            app.RemoveButton_2 = uibutton(app.PreviewPanel, 'push');
            app.RemoveButton_2.Text = 'Remove';
            app.CreateInstanceButton = uibutton(app.PreviewPanel, 'push');
            app.CreateInstanceButton.Text = 'Create Instance';
            app.SetDefaultButton = uibutton(app.PreviewPanel, 'push');
            app.SetDefaultButton.Text = 'Set Default';
            app.ExportButton_2 = uibutton(app.PreviewPanel, 'push');
            app.ExportButton_2.Text = 'Export...';
            app.GroupsPanel = uipanel(app.DVRamanToolUIFigure);
            app.GroupsPanel.Title = 'Groups';
            app.UIGroupsTable = uitable(app.GroupsPanel);
            app.UIGroupsTable.ColumnName = {''; ''; 'Name'};
            app.UIGroupsTable.RowName = {};
            app.UIGroupsTable.ColumnEditable = [false true true];
            app.UIGroupsTable.CellEditCallback = createCallbackFcn(app, @UIGroupsTableCellEdit, true);
            app.UIGroupsTable.CellSelectionCallback = createCallbackFcn(app, @UIGroupsTableCellSelection, true);
            app.ButtonGroupAdd = uibutton(app.GroupsPanel, 'push');
            app.ButtonGroupAdd.ButtonPushedFcn = createCallbackFcn(app, @ButtonGroupAddPushed, true);
            app.ButtonGroupAdd.Text = '+';
            app.ButtonGroupDelete = uibutton(app.GroupsPanel, 'push');
            app.ButtonGroupDelete.ButtonPushedFcn = createCallbackFcn(app, @ButtonGroupDeletePushed, true);
            app.ButtonGroupDelete.Text = '-';
            app.Button_3 = uibutton(app.GroupsPanel, 'push');
            app.Button_3.Text = '^';
            app.VButton = uibutton(app.GroupsPanel, 'push');
            app.VButton.Text = 'V';
            app.AssignButton = uibutton(app.GroupsPanel, 'push');
            app.AssignButton.ButtonPushedFcn = createCallbackFcn(app, @AssignButtonPushed, true);
            app.AssignButton.Tooltip = {'Assign the currently selected group to the selected samples.'};
            app.AssignButton.Text = 'Assign';
            app.TabGroup = uitabgroup(app.DVRamanToolUIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.PlottingPanel = uipanel(app.GeneralTab);
            app.PlottingPanel.Title = 'Plotting';
            app.SpectralPlotButton = uibutton(app.PlottingPanel, 'push');
            app.SpectralPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SpectralPlotButtonPushed, true);
            app.SpectralPlotButton.Text = 'Spectral Plot...';
            app.PeakAnalysisPanel = uipanel(app.GeneralTab);
            app.PeakAnalysisPanel.Title = 'Peak Analysis';
            app.ExtractPeakTableButton = uibutton(app.PeakAnalysisPanel, 'push');
            app.ExtractPeakTableButton.Text = 'Extract Peak Table';
            app.PlotPeaksButton = uibutton(app.PeakAnalysisPanel, 'push');
            app.PlotPeaksButton.Text = 'Plot Peaks';
            app.MinimumHeightEditFieldLabel = uilabel(app.PeakAnalysisPanel);
            app.MinimumHeightEditFieldLabel.Text = 'Minimum Height';
            app.MinimumHeightEditField = uieditfield(app.PeakAnalysisPanel, 'numeric');
            app.AutoCheckBox = uicheckbox(app.PeakAnalysisPanel);
            app.AutoCheckBox.Text = 'Auto';
            app.AutoCheckBox_2 = uicheckbox(app.PeakAnalysisPanel);
            app.AutoCheckBox_2.Text = 'Auto';
            app.MinimumDistanceEditFieldLabel = uilabel(app.PeakAnalysisPanel);
            app.MinimumDistanceEditFieldLabel.Text = 'Minimum Distance';
            app.MinimumDistanceEditField = uieditfield(app.PeakAnalysisPanel, 'numeric');
            app.SpectralCorrectionPanel = uipanel(app.GeneralTab);
            app.SpectralCorrectionPanel.Title = 'Spectral Correction';
            app.NormalizeButton = uibutton(app.SpectralCorrectionPanel, 'push');
            app.NormalizeButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizeButtonPushed, true);
            app.NormalizeButton.Text = 'Normalize';
            app.RemoveBaselineButton = uibutton(app.SpectralCorrectionPanel, 'push');
            app.RemoveBaselineButton.Enable = 'off';
            app.RemoveBaselineButton.Text = 'Remove Baseline...';
            app.CosmicRayRemovalButton = uibutton(app.SpectralCorrectionPanel, 'push');
            app.CosmicRayRemovalButton.Enable = 'off';
            app.CosmicRayRemovalButton.Text = 'Cosmic Ray Removal';
            app.TrimButton = uibutton(app.SpectralCorrectionPanel, 'push');
            app.TrimButton.ButtonPushedFcn = createCallbackFcn(app, @TrimButtonPushed, true);
            app.TrimButton.Text = 'Trim...';
            app.OverwriteCheckBox = uicheckbox(app.SpectralCorrectionPanel);
            app.OverwriteCheckBox.Text = 'Overwrite';
            app.WorkspacePanel = uipanel(app.GeneralTab);
            app.WorkspacePanel.Title = 'Workspace';
            app.NewButton = uibutton(app.WorkspacePanel, 'push');
            app.NewButton.Text = 'New';
            app.OpenButton = uibutton(app.WorkspacePanel, 'push');
            app.OpenButton.Text = 'Open';
            app.SaveButton = uibutton(app.WorkspacePanel, 'push');
            app.SaveButton.Text = 'Save';
            app.AreaScansTab = uitab(app.TabGroup);
            app.AreaScansTab.Title = 'Area Scans';
            app.PCATab = uitab(app.TabGroup);
            app.PCATab.Title = 'PCA';
            app.CreatePCAPanel = uipanel(app.PCATab);
            app.CreatePCAPanel.Title = 'Create PCA';
            app.AnalyzeButton = uibutton(app.CreatePCAPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.ButtonGroup_2 = uibuttongroup(app.CreatePCAPanel);
            app.EntireSpectrumButton = uiradiobutton(app.ButtonGroup_2);
            app.EntireSpectrumButton.Text = 'Entire Spectrum';
            app.EntireSpectrumButton.Value = true;
            app.RangeButton = uiradiobutton(app.ButtonGroup_2);
            app.RangeButton.Text = 'Range:';
            app.cm1EditFieldLabel = uilabel(app.ButtonGroup_2);
            app.cm1EditFieldLabel.Text = 'cm-1';
            app.PCAEndRangeField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.Label = uilabel(app.ButtonGroup_2);
            app.Label.Text = '-';
            app.PCAStartRangeField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.DAnalysisPanel = uipanel(app.PCATab);
            app.DAnalysisPanel.Title = '1-D Analysis';
            app.LoadingsButtonY = uibutton(app.DAnalysisPanel, 'push');
            app.LoadingsButtonY.ButtonPushedFcn = createCallbackFcn(app, @LoadingsButtonYPushed, true);
            app.LoadingsButtonY.Text = 'Loadings';
            app.LoadingsButtonX = uibutton(app.DAnalysisPanel, 'push');
            app.LoadingsButtonX.ButtonPushedFcn = createCallbackFcn(app, @LoadingsButtonXPushed, true);
            app.LoadingsButtonX.Text = 'Loadings';
            app.ScoresButtonX = uibutton(app.DAnalysisPanel, 'push');
            app.ScoresButtonX.Text = 'Scores';
            app.ScoresButtonY = uibutton(app.DAnalysisPanel, 'push');
            app.ScoresButtonY.Text = 'Scores';
            app.PCXSpinnerLabel = uilabel(app.DAnalysisPanel);
            app.PCXSpinnerLabel.Text = 'PC X';
            app.PCXSpinner = uispinner(app.DAnalysisPanel);
            app.PCXSpinner.Limits = [1 Inf];
            app.PCXSpinner.Value = 1;
            app.PCYSpinnerLabel = uilabel(app.DAnalysisPanel);
            app.PCYSpinnerLabel.Text = 'PC Y';
            app.PCYSpinner = uispinner(app.DAnalysisPanel);
            app.PCYSpinner.Limits = [1 Inf];
            app.PCYSpinner.Value = 2;
            app.DAnalysisPanel_2 = uipanel(app.PCATab);
            app.DAnalysisPanel_2.Title = '2-D Analysis';
            app.Scores2DScatterPlotButton = uibutton(app.DAnalysisPanel_2, 'push');
            app.Scores2DScatterPlotButton.ButtonPushedFcn = createCallbackFcn(app, @Scores2DScatterPlotButtonPushed, true);
            app.Scores2DScatterPlotButton.Text = 'Scores 2D Scatter Plot';
            app.ConfidenceIntervalButtonGroup = uibuttongroup(app.DAnalysisPanel_2);
            app.ConfidenceIntervalButtonGroup.Title = 'Confidence Interval:';
            app.RadioConfInt950 = uiradiobutton(app.ConfidenceIntervalButtonGroup);
            app.RadioConfInt950.Enable = 'off';
            app.RadioConfInt950.Text = '95%';
            app.RadioConfInt950.Value = true;
            app.RadioConfInt990 = uiradiobutton(app.ConfidenceIntervalButtonGroup);
            app.RadioConfInt990.Enable = 'off';
            app.RadioConfInt990.Text = '99%';
            app.RadioConfInt999 = uiradiobutton(app.ConfidenceIntervalButtonGroup);
            app.RadioConfInt999.Enable = 'off';
            app.RadioConfInt999.Text = '99.9%';
            app.OptionsButton = uibutton(app.DAnalysisPanel_2, 'push');
            app.OptionsButton.Text = 'Options...';
            app.ShowConfidenceEllipsesCheckBox = uicheckbox(app.DAnalysisPanel_2);
            app.ShowConfidenceEllipsesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowConfidenceEllipsesCheckBoxValueChanged, true);
            app.ShowConfidenceEllipsesCheckBox.Text = 'Show Confidence Ellipses';
            app.OverviewPanel = uipanel(app.PCATab);
            app.OverviewPanel.Title = 'Overview';
            app.ListBox = uilistbox(app.OverviewPanel);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.pcaResSummaryTable = uitable(app.OverviewPanel);
            app.pcaResSummaryTable.ColumnName = {'Name'; 'N'};
            app.pcaResSummaryTable.RowName = {};
            % Show the figure after all components are created
            app.DVRamanToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ramatgui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DVRamanToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DVRamanToolUIFigure)
        end
    end
end